classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        Switch4            matlab.ui.control.Switch
        Switch4Label       matlab.ui.control.Label
        Switch3            matlab.ui.control.Switch
        Switch3Label       matlab.ui.control.Label
        Switch2            matlab.ui.control.Switch
        Switch2Label       matlab.ui.control.Label
        Switch             matlab.ui.control.Switch
        SwitchLabel        matlab.ui.control.Label
        RojoLamp           matlab.ui.control.Lamp
        RojoLampLabel      matlab.ui.control.Label
        AzulesLamp         matlab.ui.control.Lamp
        AzulesLampLabel    matlab.ui.control.Label
        VerdeLamp          matlab.ui.control.Lamp
        VerdeLampLabel     matlab.ui.control.Label
        AmarilloLamp       matlab.ui.control.Lamp
        AmarilloLampLabel  matlab.ui.control.Label
        InicioButton       matlab.ui.control.Button
        UIAxes6            matlab.ui.control.UIAxes
        UIAxes5            matlab.ui.control.UIAxes
        UIAxes4            matlab.ui.control.UIAxes
        UIAxes3            matlab.ui.control.UIAxes
        UIAxes2            matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serialObj
    end
    
    methods (Access = public)
        
        function results = func(app)
            
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button down function: UIAxes2
        function UIAxes2ButtonDown(app, event)
            
        end

        % Button down function: UIAxes3
        function UIAxes3ButtonDown(app, event)
            
        end

        % Button down function: UIAxes4
        function UIAxes4ButtonDown(app, event)
            
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
        value = app.Switch.Value;
        if value == "On"
         write(app.serialObj, "A", 'char');
        else
        end
            
        end

        % Value changed function: Switch2
        function Switch2ValueChanged(app, event)
            value = app.Switch2.Value;
        if value == "On"
         write(app.serialObj, "B", 'char');
        else
        end
            
        end

        % Value changed function: Switch3
        function Switch3ValueChanged(app, event)
            value = app.Switch3.Value;
        if value == "On"
        write(app.serialObj, "C", 'char');
        else
        end
        end

        % Value changed function: Switch4
        function Switch4ValueChanged(app, event)
            value = app.Switch4.Value;
        if value == "On"
        write(app.serialObj, "D", 'char');
        else
        end
        end

        % Button pushed function: InicioButton
        function InicioButtonPushed(app, event)
app.Switch.Enable = 'on';
app.Switch2.Enable = 'on';
app.Switch3.Enable = 'on';
app.Switch4.Enable = 'on';
delete(instrfind('Port', 'COM3'));
s = serialport('COM3', 115200);
app.serialObj = s;
L = pi;
t = linspace(-L, L, 100); % Vector de tiempo con 100 puntos equiespaciados
k = 40;
ecg_correlation= 0;
% Inicialización de las señales
cont = 1;
h = 0; 
a0 = 0;           
a0m = a0 / 2;
h = a0m + h;

h2 = 0;
a02 = 1; % Nuevo valor para a02
a0m2 = a02 / 2;
h2 = a0m2 + h2;

for n = 1:k
    signal = zeros(size(t)); % Inicializar la señal en cero para cada iteración
    signal2 = zeros(size(t)); % Inicializar la segunda señal en cero
    
    % Calcular los términos de la Serie de Fourier y sumarlos para obtener la señal final
    for i = 1:n
        % Señal normal
        an = ((0.079577471545946 * cos(2.0943951023932 * n)) / (n + 1.5)) ...
            - ((0.079577471545946 * cos(2.0943951023932 * n)) / (n - 1.5)) ...
            + ((0.079577471545946) / (n + 1.5)) ...
            - ((0.079577471545946) / (n - 1.5)) ...
            + ((-0.95492965855136 * cos(2.6666666666667 * n)) / (n ^ 2)) ...
            - ((0.31830988618378 * sin(2.6666666666667 * n)) / (n)) ...
            + ((0.95492965855135 * cos(2.3333333333333 * n)) / (n ^ 2)) ...
            + ((5.7295779513081 * cos(3 * n)) / (n ^ 2)) ...
            + ((1.5915494309189 * sin(3 * n)) / (n)) ...
            - ((5.7295779513081 * cos(2.6666666666667 * n)) / (n ^ 2)) ...
            + ((0.31830988618379 * sin(2.6666666666667 * n)) / (n)) ...
            + ((-6.2070427805838 * cos(3.3333333333333 * n)) / (n ^ 2)) ...
            - ((0.47746482927568 * sin(3.3333333333333 * n)) / (n)) ...
            + ((6.2070427805838 * cos(3. * n)) / (n ^ 2)) ...
            - ((1.5915494309189 * sin(3. * n)) / (n)) ...
            + ((1.432394487827 * cos(3.6666666666667 * n)) / (n ^ 2)) ...
            - ((1.432394487827 * cos(3.3333333333333 * n)) / (n ^ 2)) ...
            + ((0.47746482927566 * sin(3.3333333333333 * n)) / (n)) ...
            + ((-0.47746482927568 * cos(6.2831853071796 * n)) / ((n - 1.5) * (n + 1.5))) ...
            - ((0.47746482927568 * cos(4.1887902047863 * n)) / ((n - 1.5) * (n + 1.5))); % Coeficiente de an
        bn = ((0.079577471545946 * sin(2.0943951023932 * n)) / (n + 1.5)) ...
            - ((0.079577471545946 * sin(2.0943951023932 * n)) / (n - 1.5)) ...
            + ((0.31830988618379 * cos(2.6666666666667 * n)) / (n)) ...
            - ((0.95492965855137 * sin(2.6666666666667 * n)) / (n ^ 2)) ...
            + ((0.95492965855137 * sin(2.3333333333333 * n)) / (n ^ 2)) ...
            + ((-1.5915494309189 * cos(3 * n)) / (n)) ...
            + ((5.729577951308 * sin(3 * n)) / (n ^ 2)) ...
            - ((0.31830988618378 * cos(2.6666666666667 * n)) / (n)) ...
            - ((5.729577951308 * sin(2.6666666666667 * n)) / (n ^ 2)) ...
            + ((0.47746482927568 * cos(3.3333333333333 * n)) / (n)) ...
            - ((6.2070427805838 * sin(3.3333333333333 * n)) / (n ^ 2)) ...
            + ((1.591549430919 * cos(3 * n)) / (n)) ...
            + ((6.2070427805838 * sin(3 * n)) / (n ^ 2)) ...
            + ((1.432394487827 * sin(3.6666666666667 * n)) / (n ^ 2)) ...
            - ((0.47746482927566 * cos(3.3333333333333 * n)) / (n)) ...
            - ((1.432394487827 * sin(3.3333333333333 * n)) / (n ^ 2)) ...
            + ((-0.47746482927568 * sin(6.2831853071796 * n)) / ((n - 1.5) * (n + 1.5))) ...
            - ((0.47746482927568 * sin(4.1887902047863 * n)) / ((n - 1.5) * (n + 1.5))); % Coeficiente de
        p = an .* cos(i * pi * t / L) + bn .* sin(i * pi * t / L);
        signal = signal + p + h;

        % Patología
   an2=((0.079577471545946*cos(2.0943951023932*n))/(n+1.5))-((0.079577471545946*cos(2.0943951023932*n))/(n-1.5))+((0.079577471545946)/(n+1.5))-((0.079577471545946)/(n-1.5))               +((-0.95492965855136*cos(2.6666666666667*n))/(n^(2)))-((0.31830988618378*sin(2.6666666666667*n))/(n))+((0.95492965855135*cos(2.3333333333333*n))/(n^(2)))           +((5.7295779513081*cos(3*n))/(n^2)) + ((1.5915494309189*sin(3*n))/(n)) - ((5.7295779513081*cos(2.6666666666667*n))/(n^2)) + ((0.31830988618379*sin(2.6666666666667*n))/(n))                 +((-6.2070427805838*cos(3.2553333333333*n))/(n^(2)))+((0.0066845076098596*sin(3.2553333333333*n))/(n))+((6.2070427805838*cos(3.*n))/(n^(2)))-((1.5915494309189*sin(3.*n))/(n))                       +((-0.15915494309189*cos(5.3498*n+9.4247))/(n+1.5))+((0.15915494309189*cos(5.3498*n-9.4247))/(n-1.5))+((0.15915494309189*cos(3.2553333333333*n+6.283))/(n+1.5))-((0.15915494309189*cos(3.2553333333333*n-6.283))/(n-1.5));   %coeficiente de an
    bn2=((0.079577471545946*sin(2.0943951023932*n))/(n+1.5))-((0.079577471545946*sin(2.0943951023932*n))/(n-1.5))                    +((0.31830988618379*cos(2.6666666666667*n))/(n)) - ((0.95492965855137*sin(2.6666666666667*n))/(n^2))                  + ((0.95492965855137*sin(2.3333333333333*n))/(n^2))+((-1.5915494309189*cos(3*n))/(n)) + ((5.729577951308*sin(3*n))/(n^2)) - ((0.31830988618378*cos(2.6666666666667*n))/(n)) - ((5.729577951308*sin(2.6666666666667*n))/(n^2))+                   ((-0.0066845076098595*cos(3.2553333333333*n))/(n))-((6.2070427805838*sin(3.2553333333333*n))/(n^(2)))+((1.5915494309189*cos(3.*n))/(n))+((6.2070427805838*sin(3.*n))/(n^(2)))+                     ((-0.15915494309189*sin(5.3498*n+9.4247))/(n+1.5))+((0.15915494309189*sin(5.3498*n-9.4247))/(n-1.5))+((0.15915494309189*sin(3.2553333333333*n+6.283))/(n+1.5))-((0.15915494309189*sin(3.2553333333333*n-6.283))/(n-1.5));  % coeficiente de bn
p2 = an2 .* cos(i * pi * t / L) + bn2 .* sin(i * pi * t / L);
signal2 = signal2 + p2 + h2;
end
    
% Escalar las señales al rango de 0 a 255 (8 bits) y redondear a enteros
signal = round((signal - min(signal)) * (255 / (max(signal) - min(signal))));
signal2 = round((signal2 - min(signal2)) * (255 / (max(signal2) - min(signal2))));


% Enviar los datos de ambas señales al microcontrolador
for i = 1:100
    write(s, signal(i), 'uint8');
    pause(0.001);
end
for i = 1:100
    write(s, signal2(i), 'uint8');
    pause(0.001);
end

% Leer todos los datos del microcontrolador
num_muestras = 300; % Cambiado a 300 para incluir 100 muestras de la señal ECG
datos = zeros(num_muestras, 1);
for i = 1:num_muestras
    datos(i) = str2double(fscanf(s, '%s'));
    %if cont==1
    %pause(8);
    %cont = 0;
    %end
end

% Separar los datos recibidos en tres señales
datos1 = datos(1:100);
datos2 = datos(101:200);
ecg_data = datos(201:300);

% Calcular la correlación entre ECG-normal y ECG-real
ecg_correlation = xcorr(signal, ecg_data);
ecg_corr_scaled = (ecg_correlation - min(ecg_correlation)) / (max(ecg_correlation) - min(ecg_correlation));

t_corr = linspace(-L, L, length(ecg_correlation));

% Actualizar los plots de los datos recibidos
plot(app.UIAxes, t, signal); % Graficar la señal en el primer plot
plot(app.UIAxes2, t, signal2); % Graficar la señal en el segundo plot
plot(app.UIAxes3, t, ecg_data); % Graficar la señal ECG en el tercer plot
plot(app.UIAxes4, t_corr, ecg_corr_scaled);
plot(app.UIAxes5, t, datos1); % Graficar los datos 1 en el quinto plot
plot(app.UIAxes6, t, datos2); % Graficar los datos 2 en el sexto plot
drawnow;

% Plotear la correlación
% Pausa para visualizar la gráfica antes de la próxima iteración
pause(0.001);
end

        end

        % Button down function: UIAxes5
        function UIAxes5ButtonDown(app, event)

        end

        % Button down function: UIAxes6
        function UIAxes6ButtonDown(app, event)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal ECG-Normal')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Señal ECG-Patología')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @UIAxes2ButtonDown, true);
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Señal ECG-Real')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3.ButtonDownFcn = createCallbackFcn(app, @UIAxes3ButtonDown, true);
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Correlación R-N')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.ButtonDownFcn = createCallbackFcn(app, @UIAxes4ButtonDown, true);
            app.UIAxes5 = uiaxes(app.UIFigure);
            title(app.UIAxes5, 'Recepción ECG-N')
            xlabel(app.UIAxes5, 'X')
            ylabel(app.UIAxes5, 'Y')
            zlabel(app.UIAxes5, 'Z')
            app.UIAxes5.ButtonDownFcn = createCallbackFcn(app, @UIAxes5ButtonDown, true);
            app.UIAxes6 = uiaxes(app.UIFigure);
            title(app.UIAxes6, 'Recepción ECG-P')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes6.ButtonDownFcn = createCallbackFcn(app, @UIAxes6ButtonDown, true);
            app.InicioButton = uibutton(app.UIFigure, 'push');
            app.InicioButton.ButtonPushedFcn = createCallbackFcn(app, @InicioButtonPushed, true);
            app.InicioButton.Text = 'Inicio';
            app.AmarilloLampLabel = uilabel(app.UIFigure);
            app.AmarilloLampLabel.Text = 'Amarillo';
            app.AmarilloLamp = uilamp(app.UIFigure);
            app.VerdeLampLabel = uilabel(app.UIFigure);
            app.VerdeLampLabel.Text = 'Verde';
            app.VerdeLamp = uilamp(app.UIFigure);
            app.AzulesLampLabel = uilabel(app.UIFigure);
            app.AzulesLampLabel.Text = 'Azules';
            app.AzulesLamp = uilamp(app.UIFigure);
            app.RojoLampLabel = uilabel(app.UIFigure);
            app.RojoLampLabel.Text = 'Rojo';
            app.RojoLamp = uilamp(app.UIFigure);
            app.SwitchLabel = uilabel(app.UIFigure);
            app.SwitchLabel.Text = 'Switch';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch2Label = uilabel(app.UIFigure);
            app.Switch2Label.Text = 'Switch2';
            app.Switch2 = uiswitch(app.UIFigure, 'slider');
            app.Switch2.ValueChangedFcn = createCallbackFcn(app, @Switch2ValueChanged, true);
            app.Switch3Label = uilabel(app.UIFigure);
            app.Switch3Label.Text = 'Switch3';
            app.Switch3 = uiswitch(app.UIFigure, 'slider');
            app.Switch3.ValueChangedFcn = createCallbackFcn(app, @Switch3ValueChanged, true);
            app.Switch4Label = uilabel(app.UIFigure);
            app.Switch4Label.Text = 'Switch4';
            app.Switch4 = uiswitch(app.UIFigure, 'slider');
            app.Switch4.ValueChangedFcn = createCallbackFcn(app, @Switch4ValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end